classdef test_epilepsiae < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        OutputLabel              matlab.ui.control.Label
        OutputTextArea           matlab.ui.control.TextArea
        ButtonGroup              matlab.ui.container.ButtonGroup
        TestButton_2             matlab.ui.control.RadioButton
        TrainandtestButton       matlab.ui.control.RadioButton
        OptionsButtonGroup       matlab.ui.container.ButtonGroup
        ClassbalancingEWButton   matlab.ui.control.RadioButton
        AutoencoderwithEWButton  matlab.ui.control.RadioButton
        ClassbalancingButton     matlab.ui.control.RadioButton
        ErrorWeightsButton       matlab.ui.control.RadioButton
        TestButton               matlab.ui.control.Button
        ArchitectureButtonGroup  matlab.ui.container.ButtonGroup
        ShallowNNButton          matlab.ui.control.RadioButton
        RecurrentNNButton        matlab.ui.control.RadioButton
        LSTMButton               matlab.ui.control.RadioButton
        CNNButton                matlab.ui.control.RadioButton
        DatasetsmustbeinsidedatafolderLabel  matlab.ui.control.Label
        FilenameEditField        matlab.ui.control.EditField
        FilenameEditFieldLabel   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)


            if app.ErrorWeightsButton.Value == true
                options= "_EW";
            elseif app.ClassbalancingButton.Value == true
                options= "_CB";
            elseif app.ClassbalancingEWButton.Value == true
                options= "_CB_EW";
            elseif app.AutoencoderwithEWButton.Value == true
                options= "_AE";
            end


            if app.ShallowNNButton.Value == true
                NN_name= "CNN";
            elseif app.RecurrentNNButton.Value == true
                NN_name= "CNN";
            elseif app.LSTMButton.Value == true
                NN_name= "CNN";
            elseif app.CNNButton.Value == true
                NN_name= "CNN";
            end

            my_test(NN_name, options, app.FilenameEditField.Value, app.TrainandtestButton.Value, app.OutputTextArea);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.DatasetsmustbeinsidedatafolderLabel = uilabel(app.UIFigure);
            app.DatasetsmustbeinsidedatafolderLabel.Text = 'Datasets must be inside data folder';
            app.ArchitectureButtonGroup = uibuttongroup(app.UIFigure);
            app.ArchitectureButtonGroup.Title = 'Architecture';
            app.CNNButton = uiradiobutton(app.ArchitectureButtonGroup);
            app.CNNButton.Text = 'CNN';
            app.CNNButton.Value = true;
            app.LSTMButton = uiradiobutton(app.ArchitectureButtonGroup);
            app.LSTMButton.Text = 'LSTM';
            app.RecurrentNNButton = uiradiobutton(app.ArchitectureButtonGroup);
            app.RecurrentNNButton.Text = 'Recurrent NN';
            app.ShallowNNButton = uiradiobutton(app.ArchitectureButtonGroup);
            app.ShallowNNButton.Text = 'Shallow NN';
            app.TestButton = uibutton(app.UIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.OptionsButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionsButtonGroup.Title = 'Options:';
            app.ErrorWeightsButton = uiradiobutton(app.OptionsButtonGroup);
            app.ErrorWeightsButton.Text = 'Error Weights';
            app.ErrorWeightsButton.Value = true;
            app.ClassbalancingButton = uiradiobutton(app.OptionsButtonGroup);
            app.ClassbalancingButton.Text = 'Class balancing';
            app.AutoencoderwithEWButton = uiradiobutton(app.OptionsButtonGroup);
            app.AutoencoderwithEWButton.Text = 'Autoencoder with EW';
            app.ClassbalancingEWButton = uiradiobutton(app.OptionsButtonGroup);
            app.ClassbalancingEWButton.Text = 'Class balancing + EW';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.TrainandtestButton = uiradiobutton(app.ButtonGroup);
            app.TrainandtestButton.Text = 'Train and test';
            app.TrainandtestButton.Value = true;
            app.TestButton_2 = uiradiobutton(app.ButtonGroup);
            app.TestButton_2.Text = 'Test';
            app.OutputTextArea = uitextarea(app.UIFigure);
            app.OutputLabel = uilabel(app.UIFigure);
            app.OutputLabel.Text = 'Output';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test_epilepsiae

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end