classdef Choose_classifier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        WithsoftmaxCheckBox            matlab.ui.control.CheckBox
        ActivationFunctionButtonGroup  matlab.ui.container.ButtonGroup
        logsigButton                   matlab.ui.control.RadioButton
        purelinButton                  matlab.ui.control.RadioButton
        hardlimButton                  matlab.ui.control.RadioButton
        ChooseclassifierButtonGroup    matlab.ui.container.ButtonGroup
        PatternnetworkButton           matlab.ui.control.RadioButton
        TwolayernetworkButton          matlab.ui.control.RadioButton
        OnelayernetworkButton          matlab.ui.control.RadioButton
        PerceptronwithonelayernetworkButton  matlab.ui.control.RadioButton
        AssociativememorywithonelayernetworkButton  matlab.ui.control.RadioButton
        ClassifyButton                 matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
            if app.AssociativememorywithonelayernetworkButton.Value == true
                my_classify("AssMem", app.ActivationFunctionButtonGroup.SelectedObject.Text, app.WithsoftmaxCheckBox.Value);
            elseif app.PerceptronwithonelayernetworkButton.Value == true
                my_classify("Percept", app.ActivationFunctionButtonGroup.SelectedObject.Text, app.WithsoftmaxCheckBox.Value);
            elseif app.OnelayernetworkButton.Value == true
                my_classify("OneLayer", app.ActivationFunctionButtonGroup.SelectedObject.Text, app.WithsoftmaxCheckBox.Value);
            elseif app.TwolayernetworkButton.Value == true
                my_classify("TwoLayer", app.ActivationFunctionButtonGroup.SelectedObject.Text, app.WithsoftmaxCheckBox.Value);
            elseif app.PatternnetworkButton.Value == true
                my_classify("Pattern","none", app.WithsoftmaxCheckBox.Value );
            end
        end

        % Selection changed function: ChooseclassifierButtonGroup
        function ChooseclassifierButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChooseclassifierButtonGroup.SelectedObject;
            if selectedButton == app.PatternnetworkButton 
                app.ActivationFunctionButtonGroup.Visible = "off";
                app.WithsoftmaxCheckBox.Visible = "off";
            else
                app.ActivationFunctionButtonGroup.Visible ="on";
                app.WithsoftmaxCheckBox.Visible = "on";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ClassifyButton = uibutton(app.UIFigure, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Text = 'Classify';
            app.ChooseclassifierButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseclassifierButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseclassifierButtonGroupSelectionChanged, true);
            app.ChooseclassifierButtonGroup.Title = 'Choose classifier';
            app.AssociativememorywithonelayernetworkButton = uiradiobutton(app.ChooseclassifierButtonGroup);
            app.AssociativememorywithonelayernetworkButton.Text = 'Associative memory with one layer network';
            app.AssociativememorywithonelayernetworkButton.Value = true;
            app.PerceptronwithonelayernetworkButton = uiradiobutton(app.ChooseclassifierButtonGroup);
            app.PerceptronwithonelayernetworkButton.Text = 'Perceptron with one layer network';
            app.OnelayernetworkButton = uiradiobutton(app.ChooseclassifierButtonGroup);
            app.OnelayernetworkButton.Text = 'One layer network';
            app.TwolayernetworkButton = uiradiobutton(app.ChooseclassifierButtonGroup);
            app.TwolayernetworkButton.Text = 'Two layer network';
            app.PatternnetworkButton = uiradiobutton(app.ChooseclassifierButtonGroup);
            app.PatternnetworkButton.Text = 'Pattern network';
            app.ActivationFunctionButtonGroup = uibuttongroup(app.UIFigure);
            app.ActivationFunctionButtonGroup.Title = 'Activation Function';
            app.hardlimButton = uiradiobutton(app.ActivationFunctionButtonGroup);
            app.hardlimButton.Text = 'hardlim';
            app.hardlimButton.Value = true;
            app.purelinButton = uiradiobutton(app.ActivationFunctionButtonGroup);
            app.purelinButton.Text = 'purelin';
            app.logsigButton = uiradiobutton(app.ActivationFunctionButtonGroup);
            app.logsigButton.Text = 'logsig';
            app.WithsoftmaxCheckBox = uicheckbox(app.UIFigure);
            app.WithsoftmaxCheckBox.Text = 'With softmax';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Choose_classifier

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end